classdef Comanda_Brat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Lamp_8                         matlab.ui.control.Lamp
        Lamp_7                         matlab.ui.control.Lamp
        Lamp_6                         matlab.ui.control.Lamp
        Lamp_5                         matlab.ui.control.Lamp
        Lamp_4                         matlab.ui.control.Lamp
        Lamp_3                         matlab.ui.control.Lamp
        Lamp_2                         matlab.ui.control.Lamp
        Lamp                           matlab.ui.control.Lamp
        TraiectorieefectorfinalSwitch  matlab.ui.control.Switch
        TraiectorieefectorfinalSwitchLabel  matlab.ui.control.Label
        SetatimiscareacuplelorLabel    matlab.ui.control.Label
        DensitateGripsEditField        matlab.ui.control.NumericEditField
        DensitateGripsEditFieldLabel   matlab.ui.control.Label
        timpSlider                     matlab.ui.control.Slider
        timpSliderLabel                matlab.ui.control.Label
        AlegetitimpuldesimulareLabel   matlab.ui.control.Label
        Cupla7Switch                   matlab.ui.control.Switch
        Cupla7SwitchLabel              matlab.ui.control.Label
        Cupla6Switch                   matlab.ui.control.Switch
        Cupla6SwitchLabel              matlab.ui.control.Label
        Cupla5Switch                   matlab.ui.control.Switch
        Cupla5SwitchLabel              matlab.ui.control.Label
        Cupla4Switch                   matlab.ui.control.Switch
        Cupla4SwitchLabel              matlab.ui.control.Label
        Cupla3Switch                   matlab.ui.control.Switch
        Cupla3SwitchLabel              matlab.ui.control.Label
        Cupla2Switch                   matlab.ui.control.Switch
        Cupla2SwitchLabel              matlab.ui.control.Label
        Cupla1Switch                   matlab.ui.control.Switch
        Cupla1SwitchLabel              matlab.ui.control.Label
        AlegeticupleledesprecaredoritiinformatiiLabel  matlab.ui.control.Label
        Cupla7TranslatieSlider         matlab.ui.control.Slider
        Cupla7TranslatieSliderLabel    matlab.ui.control.Label
        Cupla6TranslatieSlider         matlab.ui.control.Slider
        Cupla6TranslatieSliderLabel    matlab.ui.control.Label
        Cupla5RotatieKnob              matlab.ui.control.Knob
        Cupla5RotatieKnobLabel         matlab.ui.control.Label
        Cupla4TranslatieSlider         matlab.ui.control.Slider
        Cupla4TranslatieSliderLabel    matlab.ui.control.Label
        Cupla3RotatieKnob              matlab.ui.control.Knob
        Cupla3RotatieKnobLabel         matlab.ui.control.Label
        Cupla2RotatieKnob              matlab.ui.control.Knob
        Cupla2RotatieKnobLabel         matlab.ui.control.Label
        Cupla1TranslatieSlider         matlab.ui.control.Slider
        Cupla1TranslatieLabel          matlab.ui.control.Label
        StartSimulationButton          matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        input_cupla1 = 0; 
        input_cupla2 = 0; 
        input_cupla3 = 0; 
        input_cupla4 = 0; 
        input_cupla5 = 0; 
        input_cupla6 = 0; 
        input_cupla7 = 0; 

        informatii_cupla_1 = 'Off';
        informatii_cupla_2 = 'Off';
        informatii_cupla_3 = 'Off';
        informatii_cupla_4 = 'Off';
        informatii_cupla_5 = 'Off';
        informatii_cupla_6 = 'Off';
        informatii_cupla_7 = 'Off';
        informatii_traiectorie = 'Off';

        timp = 10;

        densitate_grips = 1000;
        
        %{
        forta_tot_cupla1 = 0;
        forta_tot_cupla2 = 0;
        forta_tot_cupla3 = 0;
        forta_tot_cupla4 = 0;
        forta_tot_cupla5 = 0;
        forta_tot_cupla6 = 0;
        forta_tot_cupla7 = 0;

        moment_tot_cupla1 = 0;
        moment_tot_cupla2 = 0;
        moment_tot_cupla3 = 0;
        moment_tot_cupla4 = 0;
        moment_tot_cupla5 = 0;
        moment_tot_cupla6 = 0;
        moment_tot_cupla7 = 0;

        x_cupla5 = 0;
        y_cupla5 = 0;
        z_cupla5 = 0;
        %}
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

                assignin("base","input_cupla_1",app.input_cupla1);
                assignin("base","input_cupla_2",app.input_cupla2);
                assignin("base","input_cupla_3",app.input_cupla3);
                assignin("base","input_cupla_4",app.input_cupla4);
                assignin("base","input_cupla_5",app.input_cupla5);
                assignin("base","input_cupla_6",app.input_cupla6);
                assignin("base","input_cupla_7",app.input_cupla7);

                assignin("base","timp",app.timp);
                assignin("base","densitate_grips",app.densitate_grips);

                app.Lamp.Color='r';
                app.Lamp_2.Color='r';
                app.Lamp_3.Color='r';
                app.Lamp_4.Color='r';
                app.Lamp_5.Color='r';
                app.Lamp_6.Color='r';
                app.Lamp_7.Color='r';
                app.Lamp_8.Color='r';
                
                %{
                assignin("base","forta_tot_cupla1",app.forta_tot_cupla1);
                assignin("base","forta_tot_cupla2",app.forta_tot_cupla2);
                assignin("base","forta_tot_cupla3",app.forta_tot_cupla3);
                assignin("base","forta_tot_cupla4",app.forta_tot_cupla4);
                assignin("base","forta_tot_cupla5",app.forta_tot_cupla5);
                assignin("base","forta_tot_cupla6",app.forta_tot_cupla6);
                assignin("base","forta_tot_cupla7",app.forta_tot_cupla7);

                assignin("base","moment_tot_cupla1",app.forta_tot_cupla1);
                assignin("base","moment_tot_cupla2",app.forta_tot_cupla2);
                assignin("base","moment_tot_cupla3",app.forta_tot_cupla3);
                assignin("base","moment_tot_cupla4",app.forta_tot_cupla4);
                assignin("base","moment_tot_cupla5",app.forta_tot_cupla5);
                assignin("base","moment_tot_cupla6",app.forta_tot_cupla6);
                assignin("base","moment_tot_cupla7",app.forta_tot_cupla7);

                assignin("base","x_cupla5",app.x_cupla5);
                assignin("base","y_cupla5",app.y_cupla5);
                assignin("base","z_cupla5",app.z_cupla5);
                %}
        end

        % Callback function
        function KnobValueChanged(app, event)
            
        end

        % Value changed function: Cupla1TranslatieSlider
        function Cupla1TranslatieSliderValueChanged(app, event)
            app.input_cupla1 = app.Cupla1TranslatieSlider.Value;
            assignin("base","input_cupla_1",app.input_cupla1);
        end

        % Value changed function: Cupla2RotatieKnob
        function Cupla2RotatieKnobValueChanged(app, event)
            app.input_cupla2 = app.Cupla2RotatieKnob.Value;
            assignin("base","input_cupla_2",app.input_cupla2);
        end

        % Value changed function: Cupla3RotatieKnob
        function Cupla3RotatieKnobValueChanged(app, event)
            app.input_cupla3 = app.Cupla3RotatieKnob.Value;
            assignin("base","input_cupla_3",app.input_cupla3);
        end

        % Value changed function: Cupla4TranslatieSlider
        function Cupla4TranslatieSliderValueChanged(app, event)
            app.input_cupla4 = app.Cupla4TranslatieSlider.Value;
            assignin("base","input_cupla_4",app.input_cupla4);
        end

        % Value changed function: Cupla5RotatieKnob
        function Cupla5RotatieKnobValueChanged(app, event)
            app.input_cupla5 = app.Cupla5RotatieKnob.Value;
            assignin("base","input_cupla_5",app.input_cupla5);
        end

        % Value changed function: Cupla6TranslatieSlider
        function Cupla6TranslatieSliderValueChanged(app, event)
            app.input_cupla6 = app.Cupla6TranslatieSlider.Value;
            assignin("base","input_cupla_6",app.input_cupla6);
        end

        % Value changed function: Cupla7TranslatieSlider
        function Cupla7TranslatieSliderValueChanged(app, event)
            app.input_cupla7 = app.Cupla7TranslatieSlider.Value;
            assignin("base","input_cupla_7",app.input_cupla7);
        end

        % Value changed function: StartSimulationButton
        function StartSimulationButtonValueChanged(app, event)

            out = sim("Brat_Robotic.slx");
            
            %close all;

            t=0:0.01:app.timp;
            forta_cupla_1=squeeze(out.forta_tot_cupla1);
            forta_cupla_2=squeeze(out.forta_tot_cupla2);
            forta_cupla_3=squeeze(out.forta_tot_cupla3);
            forta_cupla_4=squeeze(out.forta_tot_cupla4);
            forta_cupla_5=squeeze(out.forta_tot_cupla5);
            forta_cupla_6=squeeze(out.forta_tot_cupla6);
            forta_cupla_7=squeeze(out.forta_tot_cupla7);
            
            momt_cupla1=squeeze(out.moment_tot_cupla1);
            momt_cupla2=squeeze(out.moment_tot_cupla2);
            momt_cupla3=squeeze(out.moment_tot_cupla3);
            momt_cupla4=squeeze(out.moment_tot_cupla4);
            momt_cupla5=squeeze(out.moment_tot_cupla5);
            momt_cupla6=squeeze(out.moment_tot_cupla6);
            momt_cupla7=squeeze(out.moment_tot_cupla7);

            x_traiectorie=squeeze(out.x_cupla5);
            y_traiectorie=squeeze(out.y_cupla5);
            z_traiectorie=squeeze(out.z_cupla5);
            
            if strcmp(app.informatii_traiectorie,'On') == 1
                figure
                plot3(x_traiectorie(:,1),y_traiectorie(:,1),-z_traiectorie(:,1),'*k');
                xlabel('x') 
                ylabel('y') 
                zlabel('z')
                title("Traiectoria efectorului final")
                grid on
            end
 
            if strcmp(app.informatii_cupla_1,'On') == 1
                plot_informatii_cupla(forta_cupla_1(:,1:length(t)), momt_cupla1(:,1:length(t)), t, 1);
            end

            if strcmp(app.informatii_cupla_2,'On') == 1
                plot_informatii_cupla(forta_cupla_2(:,1:length(t)), momt_cupla2(:,1:length(t)), t, 2);
            end

            if strcmp(app.informatii_cupla_3,'On') == 1         
                plot_informatii_cupla(forta_cupla_3(:,1:length(t)), momt_cupla3(:,1:length(t)), t, 3);
            end

            if strcmp(app.informatii_cupla_4,'On') == 1
                plot_informatii_cupla(forta_cupla_4(:,1:length(t)), momt_cupla4(:,1:length(t)), t, 4);
            end

            if strcmp(app.informatii_cupla_5,'On') == 1
                plot_informatii_cupla(forta_cupla_5(:,1:length(t)), momt_cupla5(:,1:length(t)), t, 5);
            end

            if strcmp(app.informatii_cupla_6,'On') == 1
                plot_informatii_cupla(forta_cupla_6(:,1:length(t)), momt_cupla6(:,1:length(t)), t, 6);
            end

            if strcmp(app.informatii_cupla_7,'On') == 1
                plot_informatii_cupla(forta_cupla_7(:,1:length(t)), momt_cupla7(:,1:length(t)), t, 7);

            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
                    delete(app)
                    
        end

        % Value changed function: Cupla1Switch
        function Cupla1SwitchValueChanged(app, event)
            app.informatii_cupla_1 = app.Cupla1Switch.Value;
            if strcmp(app.informatii_cupla_1,'On')==1
                app.Lamp.Color='g';
            else
                app.Lamp.Color='r';
            end
        end

        % Value changed function: Cupla2Switch
        function Cupla2SwitchValueChanged(app, event)
            app.informatii_cupla_2 = app.Cupla2Switch.Value;
            if strcmp(app.informatii_cupla_2,'On')==1
                app.Lamp_2.Color='g';
            else
                app.Lamp_2.Color='r';
            end
        end

        % Value changed function: Cupla3Switch
        function Cupla3SwitchValueChanged(app, event)
            app.informatii_cupla_3 = app.Cupla3Switch.Value;
            if strcmp(app.informatii_cupla_3,'On')==1
                app.Lamp_3.Color='g';
            else
                app.Lamp_3.Color='r';
            end
        end

        % Value changed function: Cupla4Switch
        function Cupla4SwitchValueChanged(app, event)
            app.informatii_cupla_4 = app.Cupla4Switch.Value;
            if strcmp(app.informatii_cupla_4,'On')==1
                app.Lamp_4.Color='g';
            else
                app.Lamp_4.Color='r';
            end
        end

        % Value changed function: Cupla5Switch
        function Cupla5SwitchValueChanged(app, event)
            app.informatii_cupla_5 = app.Cupla5Switch.Value;
            if strcmp(app.informatii_cupla_5,'On')==1
                app.Lamp_5.Color='g';
            else
                app.Lamp_5.Color='r';
            end
        end

        % Value changed function: Cupla6Switch
        function Cupla6SwitchValueChanged(app, event)
            app.informatii_cupla_6 = app.Cupla6Switch.Value;
            if strcmp(app.informatii_cupla_6,'On')==1
                app.Lamp_6.Color='g';
            else
                app.Lamp_6.Color='r';
            end
        end

        % Value changed function: Cupla7Switch
        function Cupla7SwitchValueChanged(app, event)
            app.informatii_cupla_7 = app.Cupla7Switch.Value;
            if strcmp(app.informatii_cupla_7,'On')==1
                app.Lamp_7.Color='g';
            else
                app.Lamp_7.Color='r';
            end
        end

        % Value changed function: timpSlider
        function timpSliderValueChanged(app, event)
            app.timp = app.timpSlider.Value;
            assignin("base","timp",app.timp);
        end

        % Value changed function: DensitateGripsEditField
        function DensitateGripsEditFieldValueChanged(app, event)
            app.densitate_grips = app.DensitateGripsEditField.Value;
            assignin("base","densitate_grips",app.densitate_grips);
        end

        % Value changed function: TraiectorieefectorfinalSwitch
        function TraiectorieefectorfinalSwitchValueChanged(app, event)
            app.informatii_traiectorie= app.TraiectorieefectorfinalSwitch.Value;
            if strcmp(app.informatii_traiectorie,'On')==1
                app.Lamp_8.Color='g';
            else
                app.Lamp_8.Color='r';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartSimulationButton = uibutton(app.UIFigure, 'state');
            app.StartSimulationButton.ValueChangedFcn = createCallbackFcn(app, @StartSimulationButtonValueChanged, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.Cupla1TranslatieLabel = uilabel(app.UIFigure);
            app.Cupla1TranslatieLabel.Text = 'Cupla 1 (Translatie)';
            app.Cupla1TranslatieSlider = uislider(app.UIFigure);
            app.Cupla1TranslatieSlider.Limits = [0 30];
            app.Cupla1TranslatieSlider.ValueChangedFcn = createCallbackFcn(app, @Cupla1TranslatieSliderValueChanged, true);
            app.Cupla2RotatieKnobLabel = uilabel(app.UIFigure);
            app.Cupla2RotatieKnobLabel.Text = 'Cupla 2 (Rotatie)';
            app.Cupla2RotatieKnob = uiknob(app.UIFigure, 'continuous');
            app.Cupla2RotatieKnob.Limits = [0 360];
            app.Cupla2RotatieKnob.MajorTicks = [0 30 60 90 120 150 180 210 240 270 300 330 360];
            app.Cupla2RotatieKnob.MajorTickLabels = {'0', '30', '60', '90', '120', '150', '180', '210', '240', '270', '300', '330', '360'};
            app.Cupla2RotatieKnob.ValueChangedFcn = createCallbackFcn(app, @Cupla2RotatieKnobValueChanged, true);
            app.Cupla3RotatieKnobLabel = uilabel(app.UIFigure);
            app.Cupla3RotatieKnobLabel.Text = 'Cupla 3 (Rotatie)';
            app.Cupla3RotatieKnob = uiknob(app.UIFigure, 'continuous');
            app.Cupla3RotatieKnob.Limits = [-225 45];
            app.Cupla3RotatieKnob.ValueChangedFcn = createCallbackFcn(app, @Cupla3RotatieKnobValueChanged, true);
            app.Cupla4TranslatieSliderLabel = uilabel(app.UIFigure);
            app.Cupla4TranslatieSliderLabel.Text = 'Cupla 4 (Translatie)';
            app.Cupla4TranslatieSlider = uislider(app.UIFigure);
            app.Cupla4TranslatieSlider.Limits = [0 30];
            app.Cupla4TranslatieSlider.ValueChangedFcn = createCallbackFcn(app, @Cupla4TranslatieSliderValueChanged, true);
            app.Cupla5RotatieKnobLabel = uilabel(app.UIFigure);
            app.Cupla5RotatieKnobLabel.Text = 'Cupla 5 (Rotatie)';
            app.Cupla5RotatieKnob = uiknob(app.UIFigure, 'continuous');
            app.Cupla5RotatieKnob.Limits = [0 360];
            app.Cupla5RotatieKnob.MajorTicks = [0 30 60 90 120 150 180 210 240 270 300 330 360];
            app.Cupla5RotatieKnob.MajorTickLabels = {'0', '30', '60', '90', '120', '150', '180', '210', '240', '270', '300', '330', '360'};
            app.Cupla5RotatieKnob.ValueChangedFcn = createCallbackFcn(app, @Cupla5RotatieKnobValueChanged, true);
            app.Cupla6TranslatieSliderLabel = uilabel(app.UIFigure);
            app.Cupla6TranslatieSliderLabel.Text = 'Cupla 6 (Translatie)';
            app.Cupla6TranslatieSlider = uislider(app.UIFigure);
            app.Cupla6TranslatieSlider.Limits = [0 7];
            app.Cupla6TranslatieSlider.ValueChangedFcn = createCallbackFcn(app, @Cupla6TranslatieSliderValueChanged, true);
            app.Cupla7TranslatieSliderLabel = uilabel(app.UIFigure);
            app.Cupla7TranslatieSliderLabel.Text = 'Cupla 7 (Translatie)';
            app.Cupla7TranslatieSlider = uislider(app.UIFigure);
            app.Cupla7TranslatieSlider.Limits = [0 7];
            app.Cupla7TranslatieSlider.ValueChangedFcn = createCallbackFcn(app, @Cupla7TranslatieSliderValueChanged, true);
            app.AlegeticupleledesprecaredoritiinformatiiLabel = uilabel(app.UIFigure);
            app.AlegeticupleledesprecaredoritiinformatiiLabel.Text = 'Alegeti cuplele despre care doriti informatii:';
            app.Cupla1SwitchLabel = uilabel(app.UIFigure);
            app.Cupla1SwitchLabel.Text = 'Cupla 1';
            app.Cupla1Switch = uiswitch(app.UIFigure, 'slider');
            app.Cupla1Switch.ValueChangedFcn = createCallbackFcn(app, @Cupla1SwitchValueChanged, true);
            app.Cupla2SwitchLabel = uilabel(app.UIFigure);
            app.Cupla2SwitchLabel.Text = 'Cupla 2';
            app.Cupla2Switch = uiswitch(app.UIFigure, 'slider');
            app.Cupla2Switch.ValueChangedFcn = createCallbackFcn(app, @Cupla2SwitchValueChanged, true);
            app.Cupla3SwitchLabel = uilabel(app.UIFigure);
            app.Cupla3SwitchLabel.Text = 'Cupla 3';
            app.Cupla3Switch = uiswitch(app.UIFigure, 'slider');
            app.Cupla3Switch.ValueChangedFcn = createCallbackFcn(app, @Cupla3SwitchValueChanged, true);
            app.Cupla4SwitchLabel = uilabel(app.UIFigure);
            app.Cupla4SwitchLabel.Text = 'Cupla 4';
            app.Cupla4Switch = uiswitch(app.UIFigure, 'slider');
            app.Cupla4Switch.ValueChangedFcn = createCallbackFcn(app, @Cupla4SwitchValueChanged, true);
            app.Cupla5SwitchLabel = uilabel(app.UIFigure);
            app.Cupla5SwitchLabel.Text = 'Cupla 5';
            app.Cupla5Switch = uiswitch(app.UIFigure, 'slider');
            app.Cupla5Switch.ValueChangedFcn = createCallbackFcn(app, @Cupla5SwitchValueChanged, true);
            app.Cupla6SwitchLabel = uilabel(app.UIFigure);
            app.Cupla6SwitchLabel.Text = 'Cupla 6';
            app.Cupla6Switch = uiswitch(app.UIFigure, 'slider');
            app.Cupla6Switch.ValueChangedFcn = createCallbackFcn(app, @Cupla6SwitchValueChanged, true);
            app.Cupla7SwitchLabel = uilabel(app.UIFigure);
            app.Cupla7SwitchLabel.Text = 'Cupla 7';
            app.Cupla7Switch = uiswitch(app.UIFigure, 'slider');
            app.Cupla7Switch.ValueChangedFcn = createCallbackFcn(app, @Cupla7SwitchValueChanged, true);
            app.AlegetitimpuldesimulareLabel = uilabel(app.UIFigure);
            app.AlegetitimpuldesimulareLabel.Text = 'Alegeti timpul de simulare:';
            app.timpSliderLabel = uilabel(app.UIFigure);
            app.timpSliderLabel.Text = 'timp';
            app.timpSlider = uislider(app.UIFigure);
            app.timpSlider.Limits = [10 20];
            app.timpSlider.ValueChangedFcn = createCallbackFcn(app, @timpSliderValueChanged, true);
            app.timpSlider.Value = 10;
            app.DensitateGripsEditFieldLabel = uilabel(app.UIFigure);
            app.DensitateGripsEditFieldLabel.Text = 'Densitate Grips';
            app.DensitateGripsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DensitateGripsEditField.Limits = [1000 Inf];
            app.DensitateGripsEditField.ValueChangedFcn = createCallbackFcn(app, @DensitateGripsEditFieldValueChanged, true);
            app.DensitateGripsEditField.Value = 1000;
            app.SetatimiscareacuplelorLabel = uilabel(app.UIFigure);
            app.SetatimiscareacuplelorLabel.Text = 'Setati miscarea cuplelor:';
            app.TraiectorieefectorfinalSwitchLabel = uilabel(app.UIFigure);
            app.TraiectorieefectorfinalSwitchLabel.Text = 'Traiectorie efector final';
            app.TraiectorieefectorfinalSwitch = uiswitch(app.UIFigure, 'slider');
            app.TraiectorieefectorfinalSwitch.ValueChangedFcn = createCallbackFcn(app, @TraiectorieefectorfinalSwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Lamp_8 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Comanda_Brat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end